classdef SCOPE_UNAM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Label_3                       matlab.ui.control.Label
        FRECUENCIAEditField           matlab.ui.control.NumericEditField
        FRECUENCIAEditFieldLabel      matlab.ui.control.Label
        ASIGNACINDECOEFICIENTESLabel  matlab.ui.control.Label
        PUERTOBLTEditField            matlab.ui.control.EditField
        PUERTOBLTEditFieldLabel       matlab.ui.control.Label
        B2EditField                   matlab.ui.control.NumericEditField
        B2EditFieldLabel              matlab.ui.control.Label
        B1EditField                   matlab.ui.control.NumericEditField
        B1EditFieldLabel              matlab.ui.control.Label
        A2EditField                   matlab.ui.control.NumericEditField
        A2EditFieldLabel              matlab.ui.control.Label
        A1EditField                   matlab.ui.control.NumericEditField
        A1EditFieldLabel              matlab.ui.control.Label
        A0EditField                   matlab.ui.control.NumericEditField
        A0EditFieldLabel              matlab.ui.control.Label
        GRAFICARButton                matlab.ui.control.Button
        CONECTARButton                matlab.ui.control.Button
        GRAFICANDOLamp                matlab.ui.control.Lamp
        GRAFICANDOLampLabel           matlab.ui.control.Label
        BLUETOOTHLamp                 matlab.ui.control.Lamp
        BLUETOOTHLampLabel            matlab.ui.control.Label
        SALIDALabel                   matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        ENTRADALabel                  matlab.ui.control.Label
        UNIVERSIDADNACIONALAUTNOMADEMXICOFACULTADDEINGENIERALabel  matlab.ui.control.Label
        SIMULADORDEFUNCIONESDETRANSFERENCIADISCRETASLabel  matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        SALIRButton                   matlab.ui.control.Button
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %%XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%%
        %%                                               %%
        %%    UNIVERSIDAD NACIONAL AUTÓNOMA DE MÉXICO    %%
        %%            FACULTAD DE INGENIERÍA             %%
        %%      DEPARTAMENTO DE CONTROL Y ROBÓTICA       %%
        %%                                               %%
        %%        M. en I. José Antonio de Jesús         %%
        %%                   Arredondo Garza             %%
        %%                                               %%
        %%                 Agosto 2023                   %%
        %%                                               %%
        %%XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%%

        %%XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%%
        %%    DECLARACIÓN DE VARIABLES QUE SE USARÁN EN EL PROGRAMA    %%
        %%XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%%

        Size_nFFT;                  % Variable multifunción que contendrá
                                    % el número de muestras y el tamaño de
                                    % la FFT (por canal).
        Fs;                         % Frecuencia de Muestreo.
        Ts;                         % Tiempo de Muestreo.
        t;                          % Variable usada para crear el eje del
                                    % Tiempo en los ejes de graficación.
        f;                          % Variable usada para crear el eje de
                                    % la frecuencia en los ejes de
                                    % graficación.
        PUERTO;                     % Variable que contendrá el puerto
                                    % bluetooth a utilizar.
       CONECTADO;                   % Variable para indicar si el puerto
                                    % está conectado.
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %%XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
            %             INICIALIZACIÓN DE LA APLICACIÓN               %
            %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
            %  NOTA: GRABE EL ARCHIVO "FINAL_ECUATIO:py" CON EL NOMBRE  %
            %        "main:py" EN LA MEMORIA FLASH DE LA RP2040 PARA    %
            %        ASEGURAR QUE CUANDO SE ENCIENDE ESTA, EL PROGRAMA  %
            %        SE INICIE EN AUTOMÁTICO.                           %
            %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
            app.BLUETOOTHLamp.Color  = "Red";
            app.GRAFICANDOLamp.Color = "Red";
            pause(0.25);
            warndlg(['¡ LA RASPBERRY PI PICO DEBE ESTAR ENCENDIDA PARA ' ...
                      'QUE EL PROGRAMA PUEDA RECONOCERLA !'], 'MENSAJE')

            app.CONECTADO = 0;   % Indicador de conexión en cero.
        end

        % Button pushed function: SALIRButton
        function SALIRButtonPushed(app, event)
            %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
            %    SALIR DE LA APLICACIÓN    %
            %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
            close(app.UIFigure)
            clear all;
        end

        % Button pushed function: CONECTARButton
        function CONECTARButtonPushed(app, event)
            %% XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX %%
            %%                                                       %%
            %%    CÓDIGO DEL BOTÓN "CONECTAR" QUE COMUNICARÁ A LA    %%
            %%    RASPBERRY PI PICO CON EL PROGRAMA DE MATLAB QUE    %%
            %%    ESTARÁ RESIDENTE EN LA COMPUTADORA PERSONAL.       %%
            %%                                                       %%
            %% XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX %%

            app.Size_nFFT = 500;                   % Número de MUESTRAS,
            app.Fs        = 8000;                   % Frecuencia de 
                                                    % Muestreo. OJO OJO
            app.Ts        = (1/app.Fs);             % Tiempo de Muestreo.

            %xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx%
            %   Creación de los ejes de graficación   %
            %xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx%
            app.t = (0:app.Size_nFFT-1)*app.Ts;
            app.f = (0:app.Size_nFFT/2-1)*(app.Fs/app.Size_nFFT);

            %xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx%
            %   Proceso de conexión al Módulo Bluestooth   % 
            %xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx%
            if app.CONECTADO == 1
                warndlg(['¡ YA ESTÁ CONECTADO !'], 'MENSAJE')
            else
                NOMBRE = app.PUERTOBLTEditField.Value;  % Adquiere nombre 
                                                        % del BLUETOOTH.
                app.PUERTO  = bluetooth(NOMBRE,1);      % Conecta Puerto.
                app.BLUETOOTHLamp.Color = "Green";      % Pon en VERDE el
                                                        % indicador.
                app.CONECTADO = 1;                      % Pon la bandera
                                                        % de conexión.
            end
        end

        % Button pushed function: GRAFICARButton
        function GRAFICARButtonPushed(app, event)
            %% XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX %%
            %%                                                         %%
            %%   CÓDIGO DEL BOTÓN DE GRAFICACIÓN QUE PRIMERO DETECTA   %%
            %%   SI ESTÁ CONECTADO EL PUERTO BLUETOOTH.                %%
            %%                                                         %%
            %% XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX %%
            
            if app.CONECTADO == 1

                %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
                %    ADQUISICIÓN DE LAS CONSTANTES DE LA FUNCIÓN DE    %
                %    TRANSFERENCIA.                                    %
                %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
                DATA(1) = 85;                         % Caracter guía "U"
                DATA(2) = app.A0EditField.Value;      % Coeficiente A0
                DATA(3) = app.A1EditField.Value;      % Coeficiente A1
                DATA(4) = app.A2EditField.Value;      % Coeficiente A2
                DATA(5) = app.B1EditField.Value;      % Coeficiente B1
                DATA(6) = app.B2EditField.Value;      % Coeficiente B2
                DATA(7) = app.FRECUENCIAEditField.Value;  % FRECUENCIA.

                %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
                %    CONVERSIÓN DE UNIDADES "double" A FORMATO "single"  %
                %    LUEGO A HEXADECIMAL Y FINALMENTE A FORMATO DE 16    %
                %    BITS.                                               %
                %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%

                Dato(1) = DATA(1);         % Guarda el caracter guía "U"

                %xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx%
                %     Conversión a hexadecimal en 32 bits     %
                %xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx%
                D1 = sscanf(num2hex(single(DATA(2))), '%2x');   
                D2 = sscanf(num2hex(single(DATA(3))), '%2x'); 
                D3 = sscanf(num2hex(single(DATA(4))), '%2x');   
                D4 = sscanf(num2hex(single(DATA(5))), '%2x');               
                D5 = sscanf(num2hex(single(DATA(6))), '%2x');   
                D6 = sscanf(num2hex(single(DATA(7))), '%2x');

                %xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx%
                % Ordenación en formato ENDIAN y agregado a la variable %
                % "Dato".                                               %
                %xxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxxx%
                Dato(2)  = D1(4);         % Cte A0 en arreglo ENDIAN
                Dato(3)  = D1(3);               
                Dato(4)  = D1(2);
                Dato(5)  = D1(1);                
                Dato(6)  = D2(4);         % Cte A1 en arreglo ENDIAN
                Dato(7)  = D2(3);               
                Dato(8)  = D2(2);
                Dato(9)  = D2(1);    
                Dato(10) = D3(4);         % Cte A2 en arreglo ENDIAN
                Dato(11) = D3(3);               
                Dato(12) = D3(2);
                Dato(13) = D3(1);  
                Dato(14) = D4(4);         % Cte B1 en arreglo ENDIAN
                Dato(15) = D4(3);               
                Dato(16) = D4(2);
                Dato(17) = D4(1);  
                Dato(18) = D5(4);         % Cte B2 en arreglo ENDIAN
                Dato(19) = D5(3);               
                Dato(20) = D5(2);
                Dato(21) = D5(1); 
                Dato(22) = D6(4);         % FRECUENCIA en arreglo ENDIAN
                Dato(23) = D6(3); 
                Dato(24) = D6(2); 
                Dato(25) = D6(1); 
                
                %% XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX %%
                %%                                                     %%
                %%   REALIZA LA TRANSMISIÓN DE LA LLAVE "U" Y DE TODA  %% 
                %%   LOS COEFICIENTES EN FORMATO "ENDIAN", Y AL FINAL  %%
                %%   TRANSMITE EL CONTADOR DE CICLOS.                  %%
                %%                                                     %%
                %% XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX %%

                write(app.PUERTO, Dato, "uint8");
                pause(1)

                %% XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX %%
                %%                                                    %%
                %%    RECEPCIÓN DE DATOS DE LA RASPBERRY PI PICO E    %%
                %%    INICIO DEL LOOP DE GRAFICACIÓN DE DATOS.        %%
                %%                                                    %%
                %% XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX %%

                i = 1;        % Inicia contador de ciclos de graficación.
                app.GRAFICANDOLamp.Color = "Green";
                while i <= 30
                    write(app.PUERTO, "R", "string"); % Manda el cacrater
                                                      % de sincronía para
                                                      % sincronizar el
                                                      % envío de datos
                                                      % (0x52 = 82 dec).

                    %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
                    %  RECEPCIÓN DE DATOS DE LA #RP2"   %
                    %  QUE ESTÁN EN FORMATO "ENDIAN" Y  %
                    %  A QUÍ SON CONVERTIDOS A "single" %
                    %  (que equivale al float).         %
                    %XXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXXX%
                    DATOS = read(app.PUERTO, app.Size_nFFT*2,"single");
                    flush(app.PUERTO);         % Liampia el PUERTO.

                    %***************************%
                    % Separación de los canales %
                    % de datos.                 %
                    %***************************%
                    N = DATOS;   

                    Count = 1;
                    k     = 1;
                    while Count <= 1000
                        N1(k)   = N(Count);       % Muestras del Canal 1.
                        N2(k)   = N(Count + 1);   % Muestras del Canal 2.
                        Count   = Count + 2;      % Incrementa contador.
                        k       = k + 1;          % Incrementa contador de
                    end    

                    %xxxxxxxxxxxxxxxxxxxxxxxxxxxxxx%
                    % Obtención de la Transformada %
                    % Rápida de Fourier "FFT".     %
                    %xxxxxxxxxxxxxxxxxxxxxxxxxxxxxx%

                    A     = fft(N1, app.Size_nFFT); % FFT de la Señal N1 de 
                                                    % entrada.
                    A     = A(1:app.Size_nFFT/2);   % Decimación.                                
                    Ax    = abs(A);                 % Valor absoluto.
                    Ax(1) = 0;                      % Quita la componente 
                                                    % de DC.

                    B     = fft(N2, app.Size_nFFT); % FFT de la Señal N2 de 
                                                    % salida.
                    B     = B(1:app.Size_nFFT/2);   % Decimación.                                
                    Bx    = abs(B);                 % Valor absoluto.
                    Bx(1) = 0;                      % Quita la componente 
                                                    % de DC.   

                    %***************************%
                    % GRÁFICA EN EL DOMINIO DEL %
                    % TIEMPO.                   %
                    %***************************%
                    plot(app.UIAxes, app.t, N1,'b',app.t, N2,'r')
                    drawnow    

                    %**************************%
                    % GRÁFICA EN EL DOMINIO DE %
                    % LA FRECUENCIA.           %
                    %**************************%
                    plot(app.UIAxes_2, app.f, Ax,'b',app.f, Bx,'r');
                    drawnow                  

                    i = i + 1;                      % Incrementa contador
                                                    % de lazo.
                end
                app.GRAFICANDOLamp.Color = "Red";
                
            else
                warndlg(['¡ NO ESTÁ CONECTADO A UN PUERTO BLUETOOTH !'], ...
                          'MENSAJE')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'GRÁFICA EN EL DOMINIO DEL TIEMPO')
            xlabel(app.UIAxes, 'TIEMPO (Segundos)')
            ylabel(app.UIAxes, 'AMPLITUD')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'GRÁFICA EN EL DOMINIO DE LA FRECUENCIA')
            xlabel(app.UIAxes_2, 'FRECUENCIA (Hertz)')
            ylabel(app.UIAxes_2, 'MAGNITUD')
            zlabel(app.UIAxes_2, 'Z')
            app.SALIRButton = uibutton(app.UIFigure, 'push');
            app.SALIRButton.ButtonPushedFcn = createCallbackFcn(app, @SALIRButtonPushed, true);
            app.SALIRButton.Text = 'SALIR';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'UNAM.jpg');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Ingenieria.jpg');
            app.SIMULADORDEFUNCIONESDETRANSFERENCIADISCRETASLabel = uilabel(app.UIFigure);
            app.SIMULADORDEFUNCIONESDETRANSFERENCIADISCRETASLabel.Text = 'SIMULADOR DE FUNCIONES DE TRANSFERENCIA DISCRETAS DE 1ERO Y SEGUNDO ORDEN';
            app.UNIVERSIDADNACIONALAUTNOMADEMXICOFACULTADDEINGENIERALabel = uilabel(app.UIFigure);
            app.UNIVERSIDADNACIONALAUTNOMADEMXICOFACULTADDEINGENIERALabel.Text = 'UNIVERSIDAD NACIONAL AUTÓNOMA DE MÉXICO -  FACULTAD DE INGENIERÍA';
            app.ENTRADALabel = uilabel(app.UIFigure);
            app.ENTRADALabel.Text = 'ENTRADA';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '_';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '_';
            app.SALIDALabel = uilabel(app.UIFigure);
            app.SALIDALabel.Text = 'SALIDA';
            app.BLUETOOTHLampLabel = uilabel(app.UIFigure);
            app.BLUETOOTHLampLabel.Text = 'BLUETOOTH';
            app.BLUETOOTHLamp = uilamp(app.UIFigure);
            app.GRAFICANDOLampLabel = uilabel(app.UIFigure);
            app.GRAFICANDOLampLabel.Text = 'GRAFICANDO';
            app.GRAFICANDOLamp = uilamp(app.UIFigure);
            app.CONECTARButton = uibutton(app.UIFigure, 'push');
            app.CONECTARButton.ButtonPushedFcn = createCallbackFcn(app, @CONECTARButtonPushed, true);
            app.CONECTARButton.Text = 'CONECTAR';
            app.GRAFICARButton = uibutton(app.UIFigure, 'push');
            app.GRAFICARButton.ButtonPushedFcn = createCallbackFcn(app, @GRAFICARButtonPushed, true);
            app.GRAFICARButton.Text = 'GRAFICAR';
            app.A0EditFieldLabel = uilabel(app.UIFigure);
            app.A0EditFieldLabel.Text = 'A0';
            app.A0EditField = uieditfield(app.UIFigure, 'numeric');
            app.A0EditField.Value = 0.0303;
            app.A1EditFieldLabel = uilabel(app.UIFigure);
            app.A1EditFieldLabel.Text = 'A1';
            app.A1EditField = uieditfield(app.UIFigure, 'numeric');
            app.A1EditField.Value = 0.0303;
            app.A2EditFieldLabel = uilabel(app.UIFigure);
            app.A2EditFieldLabel.Text = 'A2';
            app.A2EditField = uieditfield(app.UIFigure, 'numeric');
            app.B1EditFieldLabel = uilabel(app.UIFigure);
            app.B1EditFieldLabel.Text = 'B1';
            app.B1EditField = uieditfield(app.UIFigure, 'numeric');
            app.B1EditField.Value = 0.9394;
            app.B2EditFieldLabel = uilabel(app.UIFigure);
            app.B2EditFieldLabel.Text = 'B2';
            app.B2EditField = uieditfield(app.UIFigure, 'numeric');
            app.PUERTOBLTEditFieldLabel = uilabel(app.UIFigure);
            app.PUERTOBLTEditFieldLabel.Text = 'PUERTO BLT';
            app.PUERTOBLTEditField = uieditfield(app.UIFigure, 'text');
            app.PUERTOBLTEditField.Value = 'MY_RP2';
            app.ASIGNACINDECOEFICIENTESLabel = uilabel(app.UIFigure);
            app.ASIGNACINDECOEFICIENTESLabel.Text = 'ASIGNACIÓN DE COEFICIENTES';
            app.FRECUENCIAEditFieldLabel = uilabel(app.UIFigure);
            app.FRECUENCIAEditFieldLabel.Text = 'FRECUENCIA';
            app.FRECUENCIAEditField = uieditfield(app.UIFigure, 'numeric');
            app.FRECUENCIAEditField.Value = 50;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'NOTA: Los Coeficientes B1 y B2 al obtener la ecuación en diferencias se invierten en signo. Al pusar "GRAFICAR", los coeficientes  son enviados.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SCOPE_UNAM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end